classdef docScannerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DocumentScanner             matlab.ui.Figure
        imagenOriginal              matlab.ui.control.Image
        imagenFInal                 matlab.ui.control.Image
        SelectAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        MinimumEigenButton          matlab.ui.control.RadioButton
        RegionpropsButton           matlab.ui.control.RadioButton
        SelectfilePanel             matlab.ui.container.Panel
        LoadimageButton             matlab.ui.control.Button
        ProcessPanel                matlab.ui.container.Panel
        ProcessButton               matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        image % Description
        originalImage % Description
        finalImage
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         addpath('src/');

        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.png';});
            fullPath = strcat(path,file);
            app.image = readImage(fullPath);

            % Mostramos la original
            app.imagenOriginal.ImageSource = app.image;

            app.originalImage = app.image;
        end

        % Selection changed function: SelectAlgorithmButtonGroup
        function SelectAlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectAlgorithmButtonGroup.SelectedObject;
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % Aplicamos el preprocesado (y convertimos a binaria)
            image = preprocessing(app.image);

            % Detectamos las esquinas
            corners = detectCorners(image, app.SelectAlgorithmButtonGroup.SelectedObject.Text);

            % Corregimos perspectiva
            image = fixPerspective(app.originalImage, corners);
            
            app.finalImage = image;

            app.imagenFInal.ImageSource = image; 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            imwrite(app.finalImage, 'output.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DocumentScanner = uifigure('Visible', 'off');
            app.DocumentScanner.Name = 'Cam Scanner';
            app.imagenOriginal = uiimage(app.DocumentScanner);
            app.imagenFInal = uiimage(app.DocumentScanner);
            app.SelectAlgorithmButtonGroup = uibuttongroup(app.DocumentScanner);
            app.SelectAlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectAlgorithmButtonGroupSelectionChanged, true);
            app.SelectAlgorithmButtonGroup.Title = '2. Select Algorithm';
            app.MinimumEigenButton = uiradiobutton(app.SelectAlgorithmButtonGroup);
            app.MinimumEigenButton.Text = 'Minimum Eigen';
            app.MinimumEigenButton.Value = true;
            app.RegionpropsButton = uiradiobutton(app.SelectAlgorithmButtonGroup);
            app.RegionpropsButton.Text = 'Regionprops';
            app.SelectfilePanel = uipanel(app.DocumentScanner);
            app.SelectfilePanel.Title = '1. Select file';
            app.LoadimageButton = uibutton(app.SelectfilePanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image ...';
            app.ProcessPanel = uipanel(app.DocumentScanner);
            app.ProcessPanel.Title = '3. Process';
            app.ProcessButton = uibutton(app.ProcessPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.SaveButton = uibutton(app.DocumentScanner, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.DocumentScanner.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = docScannerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DocumentScanner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DocumentScanner)
        end
    end
end